classdef app6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        Button    matlab.ui.control.Button
    end


    properties (Access = private)
        state="" % Description
            D=[];
            AT=[];
            MT=[];
            i=0
            connection
    end

    methods (Access = private)
    
        function  loop(app)


                while(1)
                    if app.state=="connect"
                        delete(instrfindall);
                        com_name = find_arduino_com_windowsOS();
                        app.connection = serial(com_name,'BaudRate',115200);
                        fopen(app.connection);
                        app.state="graphing";
                    end
                    %% graphing 
                    if app.state=="graphing"
                        while (app.connection.BytesAvailable ==0)
                        end
                        if (app.connection.BytesAvailable > 0)
                            tic
                
                                new_reading=(fscanf(app.connection));
                                app.i=app.i+1
                                if strtrim(new_reading)=="s"
                                       %-----------------------%
                                     new_reading=(fscanf(app.connection));
                                     d=str2num(new_reading);
                                     app.D =  [app.D d];
                                       %-----------------------%
                                     new_reading=(fscanf(app.connection));
                                     at=str2num(new_reading);
                                     app.AT =  [app.AT at];
                                       %-----------------------%
                                     app.MT = [ app.MT toc];
                                     plot(app.UIAxes,at,d,'*');
                                     pause(1/10000000);
                                end

                        end
                    end
                    %% end
                    if app.state=="end"
                        a=app.i
                    end
                end            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            

            hold(app.UIAxes)
            plot(app.UIAxes,0,0,"*")
            pause(1/10000000); 
           
            app.state="connect"
            loop(app);
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
                                   fprintf(app.connection,'%s',"145"); 
                                   app.state="end";
                                   loop(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app6

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end