classdef position_control_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        INPUTSPanel             matlab.ui.container.Panel
        setyourpidgainsPanel    matlab.ui.container.Panel
        KpEditFieldLabel        matlab.ui.control.Label
        KpEditField             matlab.ui.control.NumericEditField
        KiEditFieldLabel        matlab.ui.control.Label
        KiEditField             matlab.ui.control.NumericEditField
        KdEditFieldLabel        matlab.ui.control.Label
        KdEditField             matlab.ui.control.NumericEditField
        setgoalpositionPanel    matlab.ui.container.Panel
        setpointEditFieldLabel  matlab.ui.control.Label
        setpointEditField       matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
        Gauge                   matlab.ui.control.SemicircularGauge
        STARTButton             matlab.ui.control.Button
        STOPButton              matlab.ui.control.Button
        RESETButton             matlab.ui.control.Button
        SavedataButton          matlab.ui.control.Button
        UIAxes_2                matlab.ui.control.UIAxes
        Label2                  matlab.ui.control.Label
        clear_graphButton       matlab.ui.control.Button
    end


    properties (Access = public)
        state % Description
        kp=0;
        ki=0;
        kd=0;
        set_point;
        connection;
        Data_Array=[];
    end

    methods (Access = private)
    
        function  FSM_GUI(app)
            while(1)
                %% connection_checking-----------------------------------
                    if (app.state=="connection_checking")
                        app.Label2.Text=app.state;
                        app.state
                        %     1-creat connection to arduino 
                        %     2-sends 111.1 chars to the arduino and waits for "RighT" string back
                        %     3-if step 2 completed successfully it sets the FSM state to "resetting"
                        delete(instrfindall);
                        com_name = find_arduino_com_windowsOS();
                        app.connection = serial(com_name,'BaudRate',115200);
                        fopen(app.connection);
                        pause(1) % withouth this the serial print dont have enough time to reach arduino
                
                        
                        %send a message to make sure the arduino connected has the FSM code
                        %on it
                        fprintf(app.connection,'%s',"111.1");
                        % wait till there is data in the serial input buffer
                        while (app.connection.BytesAvailable ==0)
                        end
                        
                        %read the data
                        new_reading="";
                        if (app.connection.BytesAvailable > 0)
                            new_reading=(fscanf(app.connection));
                            if strtrim(new_reading)=="RighT"
                                app.state="resetting";
                            end
                        end
                
                    end
                    %% end of connection_checking-----------------------------------
                    %% resetting---------------------------------------------------
                    if (app.state=="resetting")
                        app.state
                        app.Label2.Text=app.state;
                        while (app.connection.BytesAvailable ==0)
                        end
                        
                        %read the data
                        new_reading="";
                        if (app.connection.BytesAvailable > 0)
                            new_reading=(fscanf(app.connection));
                            if strtrim(new_reading)=="ReseteD"
                                app.state="waiting_for_go_sig";
                            end
                        end
                    end
                    %% end of resetting-----------------------------------------------            
                    %% waiting_for_go_sig---------------------------------------------
                    if (app.state=="waiting_for_go_sig")
                        app.Label2.Text=app.state;
                        app.state
                        app.kp=app.KpEditField.Value;
                        app.ki=app.KiEditField.Value;
                        app.kd=app.KdEditField.Value;
                        app.set_point=app.setpointEditField.Value;
                        pause(0.5);
                    end 
                    %% end of waiting of gains ---------------------------------------
                    %% sending_gains
                    

                    if (app.state=="sending_gains")
                        app.Label2.Text=app.state;
                        app.state
                        
                        fprintf(app.connection,'%s',"222.2");
                        fprintf(app.connection,'%s',"*");
                        fprintf(app.connection,'%s',num2str(app.kp));
                        fprintf(app.connection,'%s',"*");
                        fprintf(app.connection,'%s',num2str(app.ki));
                        fprintf(app.connection,'%s',"*");
                        fprintf(app.connection,'%s',num2str(app.kd));
                        fprintf(app.connection,'%s',"*");
                        fprintf(app.connection,'%s',num2str(app.set_point));
                        app.state="waiting_for_validation";
                        pause(1);
                    end
                    %%end ofsending_gains------------------------------------
                    %% waiting_for_validation-------------------------------------------
                    if (app.state=="waiting_for_validation")
                        app.Label2.Text=app.state;
                        app.state
                        pause(10/1000000);
                        if (app.connection.BytesAvailable > 0)
                           
                                new_reading=(fscanf(app.connection));
                                if strtrim(new_reading)=="GainS"
                                    kp_tag=strtrim(fscanf(app.connection));
                                    if kp_tag=="kp"
                                       recived_kp=str2num(fscanf(app.connection));
                                    end
                                    ki_tag=strtrim(fscanf(app.connection));
                                    if ki_tag=="ki"
                                       recived_ki=str2num(fscanf(app.connection));
                                    end
                                    kd_tag=strtrim(fscanf(app.connection));
                                    if kd_tag=="kd"
                                       recived_kd=str2num(fscanf(app.connection));
                                    end
                                    sp_tag=strtrim(fscanf(app.connection));
                                    if sp_tag=="sp"
                                       recived_set_point=str2num(fscanf(app.connection));
                                    end
                                   fprintf(app.connection,'%s',"444.4");
                                   app.state="graphing";
                                end
                        end
                
                
                    end
                    %% end of waiting_for_validation-------------------------------------------                    
                    %% graphing
                    if (app.state=="graphing")
                        app.Label2.Text=app.state;
                        app.state
                        app.Data_Array=[];
                        Control_Array=[];
                        Arduino_Time_Array=[];
                        Matlab_Time_Array=[];
                        cla(app.UIAxes)
                        cla(app.UIAxes_2)
                        
                 
                        while (app.connection.BytesAvailable ==0)
                
                        end
                
                
                        new_reading="";
                
                        if (app.connection.BytesAvailable > 0)
                            tic
                            while strtrim(new_reading) ~= "e"
                                new_reading=(fscanf(app.connection));
                        %         erase(new_reading,newline)
                                if strtrim(new_reading)=="s"
                                     %-----------------------%
                                     new_reading=(fscanf(app.connection));
                                     data_point=str2num(new_reading);
                                     app.Data_Array =  [app.Data_Array data_point];
                                     %-----------------------%
                                     new_reading=(fscanf(app.connection));
                                     control_point=str2num(new_reading)/255*12;
                                     Control_Array =  [Control_Array control_point];
                                     %-----------------------%
                                     new_reading=(fscanf(app.connection));
                                     arduin_time_point=str2num(new_reading);
                                     Arduino_Time_Array =  [Arduino_Time_Array arduin_time_point];
                                     %-----------------------%
                                     matlab_time_point=toc*1000000;
                                     Matlab_Time_Array = [ Matlab_Time_Array matlab_time_point];
                                     if size(Arduino_Time_Array,2)>=2
                                         Xs=[Arduino_Time_Array(end-1) , Arduino_Time_Array(end)];
                                         Ys=[app.Data_Array(end-1) , app.Data_Array(end)];
                                         Ys2=[Control_Array(end-1) , Control_Array(end)];
                                         line(app.UIAxes,Xs,Ys,'Color','red');
                                         line(app.UIAxes_2,Xs,Ys2,'Color','blue');
                                         pause(1/1000000);        %line 11

                                     else
                                         plot(app.UIAxes,arduin_time_point,data_point,"b*");
                                         plot(app.UIAxes_2,arduin_time_point,control_point,"-x");
                                         pause(1/1000000);        %line 11
                                     end

                
                                end
                
                            end
                        end
                    end                   
            end           
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.state="connection_checking";
            app.Gauge.Value=app.setpointEditField.Value;
            app.kp = app.KpEditField.Value;
            app.ki = app.KiEditField.Value;
            app.kd = app.KdEditField.Value;
            app.set_point=app.setpointEditField.Value;
            hold(app.UIAxes);
            hold(app.UIAxes_2);
            FSM_GUI(app);
        end

        % Value changed function: setpointEditField
        function setpointEditFieldValueChanged(app, event)
            value = app.setpointEditField.Value;
            app.Gauge.Value=value;
        end

        % Value changed function: KpEditField
        function KpEditFieldValueChanged(app, event)
            value = app.KpEditField.Value;
%             app.Label_kp.Text=num2str(value);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
                if (app.state=="waiting_for_go_sig")
                        app.kp=app.KpEditField.Value;
                        app.ki=app.KiEditField.Value;
                        app.kd=app.KdEditField.Value;
                        app.set_point=app.setpointEditField.Value;
                        app.state="sending_gains";
                        FSM_GUI(app);
                        pause(.2);

                end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
                if (app.state=="graphing")
                        fprintf(app.connection,'%s',"*145*"); 
                        app.state="waiting_for_go_sig";
                        FSM_GUI(app);
                end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
                
                if (app.state~="waiting_for_validation" && app.state~="sending_gains" )
                        fprintf(app.connection,'%s',"*555*"); 
                        app.state="resetting";
                        FSM_GUI(app);
                end
        end

        % Button pushed function: clear_graphButton
        function clear_graphButtonPushed(app, event)
          if (app.state=="waiting_for_go_sig")
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            FSM_GUI(app)
          end
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            if (app.state=="waiting_for_go_sig")
                my_data= app.Data_Array;
                uisave("my_data","response")
                FSM_GUI(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'time (ms)')
            ylabel(app.UIAxes, 'angle (degree)')
            app.INPUTSPanel = uipanel(app.UIFigure);
            app.INPUTSPanel.Title = 'INPUTS';
            app.setyourpidgainsPanel = uipanel(app.INPUTSPanel);
            app.setyourpidgainsPanel.Title = 'set your pid gains';
            app.KpEditFieldLabel = uilabel(app.setyourpidgainsPanel);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.setyourpidgainsPanel, 'numeric');
            app.KpEditField.ValueChangedFcn = createCallbackFcn(app, @KpEditFieldValueChanged, true);
            app.KpEditField.Value = 10;
            app.KiEditFieldLabel = uilabel(app.setyourpidgainsPanel);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.setyourpidgainsPanel, 'numeric');
            app.KdEditFieldLabel = uilabel(app.setyourpidgainsPanel);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.setyourpidgainsPanel, 'numeric');
            app.KdEditField.Value = 0.4;
            app.setgoalpositionPanel = uipanel(app.INPUTSPanel);
            app.setgoalpositionPanel.Title = 'set goal position';
            app.setpointEditFieldLabel = uilabel(app.setgoalpositionPanel);
            app.setpointEditFieldLabel.Text = 'set point';
            app.setpointEditField = uieditfield(app.setgoalpositionPanel, 'numeric');
            app.setpointEditField.Limits = [0 180];
            app.setpointEditField.ValueChangedFcn = createCallbackFcn(app, @setpointEditFieldValueChanged, true);
            app.setpointEditField.Value = 90;
            app.Label = uilabel(app.setgoalpositionPanel);
            app.Label.Text = '';
            app.Gauge = uigauge(app.setgoalpositionPanel, 'semicircular');
            app.Gauge.Limits = [0 180];
            app.STARTButton = uibutton(app.INPUTSPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.INPUTSPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.RESETButton = uibutton(app.INPUTSPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.SavedataButton = uibutton(app.UIFigure, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'time (ms)')
            ylabel(app.UIAxes_2, 'control output (Volts)')
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.clear_graphButton = uibutton(app.UIFigure, 'push');
            app.clear_graphButton.ButtonPushedFcn = createCallbackFcn(app, @clear_graphButtonPushed, true);
            app.clear_graphButton.Text = 'clear_graph';
        end
    end

    methods (Access = public)

        % Construct app
        function app = position_control_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end